classdef imageMixer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        axes1                       matlab.ui.control.UIAxes
        axes2                       matlab.ui.control.UIAxes
        axes3                       matlab.ui.control.UIAxes
        combine                     matlab.ui.control.Button
        LoadFirstImageButton        matlab.ui.control.Button
        LoadSecondImageButton       matlab.ui.control.Button
        MixingLtoRSliderLabel       matlab.ui.control.Label
        MixingLtoRSlider            matlab.ui.control.Slider
        ImageOverlayingSettingsDropDownLabel  matlab.ui.control.Label
        ImageOverlayingSettingsDropDown  matlab.ui.control.DropDown
        ImageBlendSettingsDropDownLabel  matlab.ui.control.Label
        ImageBlendSettingsDropDown  matlab.ui.control.DropDown
        StretchImageCheckBox        matlab.ui.control.CheckBox
        StretchImageCheckBox_2      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        img1Loaded=false;
        img2Loaded=false;
        
        img1 
        img2
        
        combine1on2     ='0';
        combine2on1     ='1';
        combineBoth     ='2';
        
        mixStd          ='10';
        mixDark         ='20';
        mixLight        ='30';
        mixDissolve     ='40';
        mixMultiply     ='50';
        mixScreen       ='60';
        mixOverlay	='70';
        mixHardLight	='80';	
        mixSoftLight	='90';	
        mixColorDodge	='100';	
        mixDivide	='110';
        mixAddition	='120';
        mixSubtract     ='130';
        mixDifference	='140';	
    end
    
    methods (Access = public)
        function combineImages(app)
            [v1 v2] = app.sliderValue();
            s1 = size(app.img1);
            s2 = size(app.img2);
            img3 = 0;
            layerA = 0;
            layerB = 0;
            combineType = app.ImageOverlayingSettingsDropDown.Value;
            switch combineType
                case app.combineBoth
                    img3 = double(zeros(max(s1,s2)));
                    layerA = app.stretchImage(app.img1, size(img3), app.StretchImageCheckBox.Value == true);
                    layerB = app.stretchImage(app.img2, size(img3), app.StretchImageCheckBox_2.Value == true);
                    s1 = size(layerA);
                    s2 = size(layerB);
                    img3(1:s1(1),1:s1(2),:) = layerA(1:s1(1),1:s1(2),:);
                    img3(1:s2(1),1:s2(2),:) = layerB(1:s2(1),1:s2(2),:);
                case app.combine1on2
                    img3 = app.img1;
                    layerA = app.stretchImage(app.img1, size(img3), app.StretchImageCheckBox.Value == true);
                    layerB = app.stretchImage(app.img2, size(img3), app.StretchImageCheckBox_2.Value == true);
                    s1 = size(layerA);
                    s2 = size(layerB);
                    s2 = min(s1,s2);
                case app.combine2on1
                    img3 = app.img2;
                    layerB = app.stretchImage(app.img1, size(img3), app.StretchImageCheckBox.Value == true);
                    layerA = app.stretchImage(app.img2, size(img3), app.StretchImageCheckBox_2.Value == true);
                    s1 = size(layerA);
                    s2 = size(layerB);
                    s1 = min(s1,s2);
            end
            mixType = app.ImageBlendSettingsDropDown.Value;
            [v1 v2] = app.sliderValue();
            s3 = min(s1,s2);
            img3(1:s3(1),1:s3(2),:) = app.getColor(layerA(1:s3(1),1:s3(2),:),layerB(1:s3(1),1:s3(2),:),mixType,v1,v2);
            app.showImage(app.axes3,img3);
        end
        
        function [v1,v2]=sliderValue(app)
            value = app.MixingLtoRSlider.Value;
            v2 = ((value + 100) / 2)/100;
            v1 = 1-v2;
        end
        
        function c=getColor(app,c1,c2, mixType,v1,v2)
            c=c1;
            switch mixType
                case app.mixStd
                        c = c1.*v1+c2.*v2;
                case app.mixDark
                        c = min(c1,c2);
                case app.mixLight
                        c = max(c1,c2);
                case app.mixDissolve
                          s = size(c1);
                          r = rand(s(1),s(2));
                          c = c1;
                          for x=1:s(1)
                              for y =1:s(2)
                                  if(r(x,y)>v1)
                                      c(x,y,:)=c2(x,y,:);
                                  end
                              end
                          end
                case app.mixMultiply  
                        c = c1.*c2;
                case app.mixScreen
                        one=ones(size(c1));
                        c = one-(one-c1).*(one-c2);
                case app.mixOverlay
                        s = size(c1);  
                        c = c1;
                        one = c(1,1,:);
                        one(:,:,:)=1;
                          for x=1:s(1)
                              for y =1:s(2)
                                  if(mean(c1(x,y,:)<0.5))
                                      c(x,y,:)=2.*c1(x,y,:).*c2(x,y,:);
                                  else
                                      c(x,y,:)=one-2.*(one-c1(x,y,:)).*(one-c2(x,y,:));
                                  end
                              end
                          end
                case app.mixHardLight
                        s = size(c1);  
                        c = c1+c2;
                          for x=1:s(1)
                              for y =1:s(2)
                                  if(mean(c(x,y,:)<0.5))
                                      c(x,y,:)=1 - (c1(x,y,:) + c2(x,y,:));
                                  end
                              end
                          end
                case app.mixSoftLight
                        s = size(c1);  
                        c = c1;
                        one = c(1,1,:);
                        one(:,:,:)=1;
                          for x=1:s(1)
                              for y =1:s(2)
                                  if(mean(c2(x,y,:)<0.5))
                                      c(x,y,:)=2.*c1(x,y,:).*c2(x,y,:) + (c1(x,y,:).^2).*(one - 2.*c2(x,y,:));
                                  else
                                      c(x,y,:)=2.*c1(x,y,:).*(one-c2(x,y,:))+(c1(x,y,:).^(1/2)).*(2.*c2(x,y,:)-one);
                                  end
                              end
                          end
                case app.mixColorDodge
                        one=ones(size(c1));
                        c = one - (one-c2)./(c1);	
                case app.mixDivide
                        c = c2./c1;	
                case app.mixAddition
                        c = c2+c1;
                case app.mixSubtract
                   	c = c2-c1;
                case app.mixDifference	
                        s = size(c1);  
                        c = c1;
                          for x=1:s(1)
                              for y =1:s(2)
                                  c(x,y,:) = app.dif(c1(x,y,:),c2(x,y,:));
                              end
                          end
            end
        end
        
        function c=dif(app,c1, c2)
            c = c1-c2;
            if c(1,1,1)<0
                c(1,1,1) = c2(1,1,1)-c1(1,1,1);
            end
            if c(1,1,2)<0
                c(1,1,2) = c2(1,1,2) - c1(1,1,1);
            end
            if(c(1,1,3)<0)
                c(1,1,3) = c2(1,1,3) - c1(1,1,3);
            end
        end
        
        function strImg=stretchImage(app, img, s, stretch)
            strImg =img;
            if stretch
                strImg = imresize(img, [s(1) s(2)]);
            end
        end
        
        function showImage(app, ax, img)
            imshow(img, 'parent', ax);
        end
        
        function b=imageLoaded(app)
            b = app.img1Loaded && app.img2Loaded;
        end
        
        function img=loadImage(app)
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.png';'*.PNG';'*.JPG';'*.tiff'}, 'Select file');
            img = double(imread(fullfile(path,file)))./256;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imageMixer_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to imageMixer (see VARARGIN)
%             
%             % Choose default command line output for imageMixer
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: combine
        function combine_Callback(app, event)
            % hObject    handle to combine (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if app.imageLoaded()
                app.combineImages();
            end
        end

        % Button pushed function: LoadFirstImageButton
        function LoadFirstImageButtonPushed(app, event)
            app.img1 = app.loadImage();
            %app.img1 = double(imread('land.jpg'))./256;
            app.img1Loaded=true;
            app.showImage(app.axes1,app.img1);
        end

        % Button pushed function: LoadSecondImageButton
        function LoadSecondImageButtonPushed(app, event)
            app.img2 = app.loadImage();
            %app.img2 = double(imread('person.jpg'))./256;
            app.img2Loaded=true;
            app.showImage(app.axes2,app.img2);
        end

        % Value changed function: MixingLtoRSlider
        function MixingLtoRSliderValueChanged(app, event)
            app.combine_Callback(event);
        end

        % Value changed function: ImageOverlayingSettingsDropDown
        function ImageOverlayingSettingsDropDownValueChanged(app, event)
            app.combine_Callback(event);
        end

        % Value changed function: ImageBlendSettingsDropDown
        function ImageBlendSettingsDropDownValueChanged(app, event)
            value = app.ImageBlendSettingsDropDown.Value;
            s='off';
            if strcmpi(value, app.mixStd) || strcmpi(value,app.mixDissolve)
                s='on';
            end
            app.MixingLtoRSlider.Visible = s;
            app.MixingLtoRSliderLabel.Visible = s;
            app.combine_Callback(event);
        end

        % Value changed function: StretchImageCheckBox
        function StretchImageCheckBoxValueChanged(app, event)
            value = app.StretchImageCheckBox.Value;
            app.combine_Callback(event);
        end

        % Value changed function: StretchImageCheckBox_2
        function StretchImageCheckBox_2ValueChanged(app, event)
            value = app.StretchImageCheckBox_2.Value;
            app.combine_Callback(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'imageMixer';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Visible = 'off';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Visible = 'off';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Visible = 'off';
            app.combine = uibutton(app.figure1, 'push');
            app.combine.ButtonPushedFcn = createCallbackFcn(app, @combine_Callback, true);
            app.combine.Text = 'Combine';
            app.LoadFirstImageButton = uibutton(app.figure1, 'push');
            app.LoadFirstImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFirstImageButtonPushed, true);
            app.LoadFirstImageButton.Text = 'Load First Image';
            app.LoadSecondImageButton = uibutton(app.figure1, 'push');
            app.LoadSecondImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSecondImageButtonPushed, true);
            app.LoadSecondImageButton.Text = 'Load Second Image';
            app.MixingLtoRSliderLabel = uilabel(app.figure1);
            app.MixingLtoRSliderLabel.Text = {'Mixing '; 'L to R'};
            app.MixingLtoRSlider = uislider(app.figure1);
            app.MixingLtoRSlider.Limits = [-100 100];
            app.MixingLtoRSlider.MajorTicks = [-100 -60 -20 20 60 100];
            app.MixingLtoRSlider.ValueChangedFcn = createCallbackFcn(app, @MixingLtoRSliderValueChanged, true);
            app.ImageOverlayingSettingsDropDownLabel = uilabel(app.figure1);
            app.ImageOverlayingSettingsDropDownLabel.Text = 'Image Overlaying Settings ';
            app.ImageOverlayingSettingsDropDown = uidropdown(app.figure1);
            app.ImageOverlayingSettingsDropDown.Items = {'Overlay 1 on 2', 'Overlay 2 on 1', 'Combine both'};
            app.ImageOverlayingSettingsDropDown.ItemsData = {'0', '1', '2'};
            app.ImageOverlayingSettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageOverlayingSettingsDropDownValueChanged, true);
            app.ImageOverlayingSettingsDropDown.Value = '2';
            app.ImageBlendSettingsDropDownLabel = uilabel(app.figure1);
            app.ImageBlendSettingsDropDownLabel.Text = 'Image Blend Settings ';
            app.ImageBlendSettingsDropDown = uidropdown(app.figure1);
            app.ImageBlendSettingsDropDown.Items = {'Standart', 'Darker get priority', 'Lighter get priority', 'Dissolve ', 'Multiply ', 'Screen', 'Overlay', 'Hard Light', 'Soft Light', 'Color Dodge ', 'Divide', 'Addition', 'Subtract', 'Difference'};
            app.ImageBlendSettingsDropDown.ItemsData = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '110', '120', '130', '140'};
            app.ImageBlendSettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageBlendSettingsDropDownValueChanged, true);
            app.ImageBlendSettingsDropDown.Value = '10';
            app.StretchImageCheckBox = uicheckbox(app.figure1);
            app.StretchImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @StretchImageCheckBoxValueChanged, true);
            app.StretchImageCheckBox.Text = 'Stretch Image';
            app.StretchImageCheckBox_2 = uicheckbox(app.figure1);
            app.StretchImageCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @StretchImageCheckBox_2ValueChanged, true);
            app.StretchImageCheckBox_2.Text = 'Stretch Image';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageMixer_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @imageMixer_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end